classdef format3000 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        FileInformationTab              matlab.ui.container.Tab
        EmailEditFieldLabel             matlab.ui.control.Label
        EmailEditField                  matlab.ui.control.EditField
        CreationDateDatePickerLabel     matlab.ui.control.Label
        CreationDateDatePicker          matlab.ui.control.DatePicker
        StartDateDatePickerLabel        matlab.ui.control.Label
        StartDateDatePicker             matlab.ui.control.DatePicker
        EndDateDatePickerLabel          matlab.ui.control.Label
        EndDateDatePicker               matlab.ui.control.DatePicker
        ContactEditFieldLabel           matlab.ui.control.Label
        ContactEditField                matlab.ui.control.EditField
        CountryCodeDropDownLabel        matlab.ui.control.Label
        CountryCodeDropDown             matlab.ui.control.DropDown
        TimeEditFieldLabel              matlab.ui.control.Label
        TimeEditField                   matlab.ui.control.EditField
        TimeEditField_2Label            matlab.ui.control.Label
        TimeEditField_2                 matlab.ui.control.EditField
        TimeEditField_3Label            matlab.ui.control.Label
        TimeEditField_3                 matlab.ui.control.EditField
        InstitutionEditFieldLabel       matlab.ui.control.Label
        InstitutionEditField            matlab.ui.control.EditField
        Lamp_Email                      matlab.ui.control.Lamp
        Lamp_CreationDate               matlab.ui.control.Lamp
        Lamp_StartDate                  matlab.ui.control.Lamp
        Lamp_EndDate                    matlab.ui.control.Lamp
        Lamp_Institution                matlab.ui.control.Lamp
        Lamp_Contact                    matlab.ui.control.Lamp
        Lamp_CountryCode                matlab.ui.control.Lamp
        Lamp_StationCode                matlab.ui.control.Lamp
        SelectDropDownLabel             matlab.ui.control.Label
        SelectDropDown                  matlab.ui.control.DropDown
        StationCodeEditFieldLabel       matlab.ui.control.Label
        StationCodeEditField            matlab.ui.control.EditField
        SelectDropDown_2Label           matlab.ui.control.Label
        SelectDropDown_StationCode      matlab.ui.control.DropDown
        Image                           matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        Image_3                         matlab.ui.control.Image
        Image_4                         matlab.ui.control.Image
        Image_5                         matlab.ui.control.Image
        Image_6                         matlab.ui.control.Image
        Image_7                         matlab.ui.control.Image
        Image_8                         matlab.ui.control.Image
        MetadataTab                     matlab.ui.container.Tab
        CreateUUIDButton                matlab.ui.control.Button
        HydrophoneTypeDropDownLabel     matlab.ui.control.Label
        HydrophoneTypeDropDown          matlab.ui.control.DropDown
        HydrophoneSerialNumberEditFieldLabel  matlab.ui.control.Label
        HydrophoneSerialNumberEditField  matlab.ui.control.EditField
        RecorderTypeDropDownLabel       matlab.ui.control.Label
        RecorderTypeDropDown            matlab.ui.control.DropDown
        RecorderSerialNumberEditFieldLabel  matlab.ui.control.Label
        RecorderSerialNumberEditField   matlab.ui.control.EditField
        MeasurementHeightEditFieldLabel  matlab.ui.control.Label
        MeasurementHeightEditField      matlab.ui.control.EditField
        MeasurementPurposeDropDownLabel  matlab.ui.control.Label
        MeasurementPurposeDropDown      matlab.ui.control.DropDown
        MeasurementSetupDropDownLabel   matlab.ui.control.Label
        MeasurementSetupDropDown        matlab.ui.control.DropDown
        RigDesignDropDownLabel          matlab.ui.control.Label
        RigDesignDropDown               matlab.ui.control.DropDown
        FrequencyCountSpinnerLabel      matlab.ui.control.Label
        FrequencyCountSpinner           matlab.ui.control.Spinner
        FrequencyUnitDropDownLabel      matlab.ui.control.Label
        FrequencyUnitDropDown           matlab.ui.control.DropDown
        ChannelCountSpinnerLabel        matlab.ui.control.Label
        ChannelCountSpinner             matlab.ui.control.Spinner
        MeasurementTotalNumbersSpinnerLabel  matlab.ui.control.Label
        MeasurementTotalNumbersSpinner  matlab.ui.control.Spinner
        MeasurementUnitDropDownLabel    matlab.ui.control.Label
        MeasurementUnitDropDown         matlab.ui.control.DropDown
        AveragingTimeSpinnerLabel       matlab.ui.control.Label
        AveragingTimeSpinner            matlab.ui.control.Spinner
        ProcessingAlgorithmDropDownLabel  matlab.ui.control.Label
        ProcessingAlgorithmDropDown     matlab.ui.control.DropDown
        DataUUIDEditFieldLabel          matlab.ui.control.Label
        DataUUIDEditField               matlab.ui.control.EditField
        DatasetVersionSpinnerLabel      matlab.ui.control.Label
        DatasetVersionSpinner           matlab.ui.control.Spinner
        CalibrationProcedureDropDownLabel  matlab.ui.control.Label
        CalibrationProcedureDropDown    matlab.ui.control.DropDown
        CalibrationDatetimeEditFieldLabel  matlab.ui.control.Label
        CalibrationDatetimeEditField    matlab.ui.control.EditField
        CommentsEditFieldLabel          matlab.ui.control.Label
        CommentsEditField               matlab.ui.control.EditField
        Lamp_HydrophoneType             matlab.ui.control.Lamp
        Lamp_HydrophoneSerialNumber     matlab.ui.control.Lamp
        Lamp_RecorderType               matlab.ui.control.Lamp
        Lamp_RecorderSerialNumber       matlab.ui.control.Lamp
        Lamp_MeasurementHeight          matlab.ui.control.Lamp
        Lamp_MeasurementPurpose         matlab.ui.control.Lamp
        Lamp_MeasurementSetup           matlab.ui.control.Lamp
        Lamp_RigDesign                  matlab.ui.control.Lamp
        Lamp_FrequencyCount             matlab.ui.control.Lamp
        Lamp_FrequencyIndex             matlab.ui.control.Lamp
        FrequencyIndexPanel             matlab.ui.container.Panel
        individualFrequencyBandsCheckBox  matlab.ui.control.CheckBox
        consecutiveThirdOctaveBandsCheckBox  matlab.ui.control.CheckBox
        LowestTOBDropDownLabel          matlab.ui.control.Label
        LowestTOBDropDown               matlab.ui.control.DropDown
        HighestTOBDropDownLabel         matlab.ui.control.Label
        HighestTOBDropDown              matlab.ui.control.DropDown
        FrequencyIndexEditField         matlab.ui.control.EditField
        LoadFrequencyBandsfromCheckBox  matlab.ui.control.CheckBox
        matButton                       matlab.ui.control.Button
        csvButton                       matlab.ui.control.Button
        h5Button                        matlab.ui.control.Button
        DataTab                         matlab.ui.container.Tab
        SelectDatetimeVectorButton      matlab.ui.control.Button
        DateTimeLabel                   matlab.ui.control.Label
        SelectLeqMeasurementsofChannel1Button  matlab.ui.control.Button
        LeqMeasurementsofChannel1Label  matlab.ui.control.Label
        SelectLeqMeasurementsofChannel2Button  matlab.ui.control.Button
        LeqMeasurementsofChannel2Label  matlab.ui.control.Label
        SelectLeqMeasurementsofChannel3Button  matlab.ui.control.Button
        LeqMeasurementsofChannel3Label  matlab.ui.control.Label
        SelectLeqMeasurementsofChannel4Button  matlab.ui.control.Button
        LeqMeasurementsofChannel4Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Email % Description
        CreationDate % Description
        StartDate
        EndDate
        Institution
        Contact
        CountryCode
        StationCode
        libpath = addpath('./libs')
        HydrophoneType % Description
        HydrophoneSerialNumber % Description
        RecorderType % Description
        RecorderSerialNumber % Description
        MeasurementHeight
        MeasurementPurpose % Description
        MeasurementSetup % Description
        RigDesign % Description
        FrequencyCount % Description
        fi_l % lower boundary of frequency index
        fi_u % upper boundary of frequency index
        fi % frequency index
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EmailEditField
        function EmailEditFieldValueChanged(app, event)
            Email = app.EmailEditField.Value;
            p = max(strfind(Email,'.'));
            at = strfind(Email,'@');
            if ~isempty(p) && ~isempty(at) && at<p
                app.Email = Email;
                app.Lamp_Email.Color = 'g';
            else
                errordlg('The emailadress, which you have entered is not valid. Please try again')
                app.Lamp_Email.Color = 'r';
            end
        end

        % Value changed function: CreationDateDatePicker
        function CreationDateDatePickerValueChanged(app, event)
            app.CreationDate = app.CreationDateDatePicker.Value;
            app.CreationDate = char(datetime(app.CreationDate,'Format','dd/MM/yyyy HH:mm:ss'));
            app.Lamp_CreationDate.Color = 'g';
        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            creationtime = datetime(app.TimeEditField.Value,"Format","HH:mm");
            creaHH = hour(creationtime);
            creamm = minute(creationtime);
            if exist('app.CreationDate','var')
            app.CreationDate = datetime(app.CreationDate) + hours(creaHH) + minutes(creamm);
            app.CreationDate = char(datetime(app.CreationDate,'Format','dd/MM/yyyy HH:mm:ss'));
            else
                errordlg('Please enter date.')
            end
%             disp(app.CreationDate)
        end

        % Value changed function: StartDateDatePicker
        function StartDateDatePickerValueChanged(app, event)
            app.StartDate = app.StartDateDatePicker.Value;
            app.StartDate = char(datetime(app.StartDate,'Format','dd/MM/yyyy HH:mm:ss'));
            app.Lamp_StartDate.Color = 'g';
        end

        % Value changed function: TimeEditField_2
        function TimeEditField_2ValueChanged(app, event)
            starttime = app.TimeEditField_2.Value;
            startHH = hour(starttime);
            startmm = minute(starttime);
            if exist('app.StartDate','var')
            app.StartDate = datetime(app.StartDate) + hours(startHH) + minutes(startmm);
            app.StartDate = char(datetime(app.StartDate,'Format','dd/MM/yyyy HH:mm:ss'));
            else
                errordlg('Please enter date.')
            end
        end

        % Value changed function: EndDateDatePicker
        function EndDateDatePickerValueChanged(app, event)
            app.EndDate = app.EndDateDatePicker.Value;
            app.EndDate = char(datetime(app.EndDate,'Format','dd/MM/yyyy HH:mm:ss'));
            app.Lamp_EndDate.Color = 'g';
        end

        % Value changed function: TimeEditField_3
        function TimeEditField_3ValueChanged(app, event)
            endtime = app.TimeEditField_2.Value;
            endHH = hour(endtime);
            endmm = minute(endtime);
            if exist('app.EndDate','var')
            app.EndDate = datetime(app.EndDate) + hours(endHH) + minutes(endmm);
            app.EndDate = char(datetime(app.EndDate,'Format','dd/MM/yyyy HH:mm:ss'));
            else
                errordlg('Please enter date.')
            end
        end

        % Value changed function: InstitutionEditField
        function InstitutionEditFieldValueChanged(app, event)
            if app.Lamp_Institution.Color == [0,1,0]
                app.Lamp_Institution.Color = 'r';
            end
            institution = app.InstitutionEditField.Value;
            clear C
            fileID = fopen('.\libs\RECO_Export_07-14-2021-01-14-31.csv');
            Q = textscan(fileID,'%s %s %s %s %s %s %s %s %s %s','delimiter',',');
            fclose(fileID);
            matchstr=institution;
            ix=contains(Q{1,3},matchstr);
            idx = find(ix==1);
            C{1}='Select ...';
            for ii=1:length(idx)
                name=char(Q{1,3}(idx(ii)));
                C{ii+1} = name;
            end
            app.SelectDropDown.Items = C;
        end

        % Value changed function: SelectDropDown
        function SelectDropDownValueChanged(app, event)
            Institution = app.SelectDropDown.Value;
            fileID = fopen('.\libs\RECO_Export_07-14-2021-01-14-31.csv');
            Q = textscan(fileID,'%s %s %s %s %s %s %s %s %s %s','delimiter',',');
            fclose(fileID);
            matchstr=Institution;
            ix=isequal(Q{1,3},matchstr);
            idx = find(ix==1);
            if length(idx)>1
                errordlg('More than one Institution with this name were found. Please try again or enter Institution code from: https://vocab.ices.dk/?ref=1398')
            else
                app.Institution = char(Q{1,2}(idx));
                app.Lamp_Institution.Color = 'g';
            end  
        end

        % Value changed function: ContactEditField
        function ContactEditFieldValueChanged(app, event)
            Contact = app.ContactEditField.Value;
            p = max(strfind(Contact,'.'));
            at = strfind(Contact,'@');
            if ~isempty(p) && ~isempty(at) && at<p
                app.Contact = Contact;
                app.Lamp_Contact.Color = 'g';
            else
                errordlg('The emailadress, which you have entered is not valid. Please try again')
                app.Lamp_Contact.Color = 'r';
            end
        end

        % Value changed function: CountryCodeDropDown
        function CountryCodeDropDownValueChanged(app, event)
            app.CountryCode = app.CountryCodeDropDown.Value;
            app.Lamp_CountryCode.Color = 'g';
        end

        % Value changed function: StationCodeEditField
        function StationCodeEditFieldValueChanged(app, event)

            StationCode = app.StationCodeEditField.Value;
            clear C
            fileID = fopen('.\libs\StationCode.csv');
            Q = textscan(fileID,'%s %s %s %s %s %s %s %s %s %s','delimiter',',');
            fclose(fileID);
            matchstr=StationCode;
            ix=contains(Q{1,3},matchstr);
            idx = find(ix==1);
            C{1}='Select ...';
            for ii=1:length(idx)
                name=char(Q{1,3}(idx(ii)));
                C{ii+1} = name;
            end
            app.SelectDropDown_StationCode.Items = C;
        end

        % Value changed function: SelectDropDown_StationCode
        function SelectDropDown_StationCodeValueChanged(app, event)
            StationCode = app.SelectDropDown.Value;
            if app.Lamp_StationCode.Color == [0,1,0]
                app.Lamp_StationCode.Color = 'r';
            else
            if isequal(StationCode, 'Select ...') 
                app.Lamp_HydrophoneType.Color = 'r';
            else
            fileID = fopen('.\libs\StationCode.csv');
            Q = textscan(fileID,'%s %s %s %s %s %s %s %s %s %s','delimiter',',');
            fclose(fileID);
            matchstr=StationCode;
            ix=isequal(Q{1,3},matchstr);
            idx = find(ix==1);
            if length(idx)>1
                errordlg('More than one Station with this station code were found. Please try again or enter station code from: https://vocab.ices.dk/?ref=1399')
            else
                app.StationCode = char(Q{1,2}(idx));
                app.Lamp_StationCode.Color = 'g';
            end
            end  
            end
        end

        % Value changed function: HydrophoneTypeDropDown
        function HydrophoneTypeDropDownValueChanged(app, event)
            HydrophoneType = app.HydrophoneTypeDropDown.Value;
            if isequal(HydrophoneType, 'Select ...') 
                app.Lamp_HydrophoneType.Color = 'r';
            else
            fileID = fopen('.\libs\HydrophoneType.csv');
            C = textscan(fileID,'%s %s %s %s %s %s %s %s','delimiter',',');
            fclose(fileID);
            matchstr=HydrophoneType;
            ix=isequal(C{1,3},matchstr);
            idx = find(ix==1);
            if length(idx)>1
                errordlg('More than one Hydrophone with this name were found. Please report incostency in Hydrophone type vocabulary to ICES (accessions@ices.dk).')
            else
                app.HydrophoneType =  C{1,2}(idx);
                app.Lamp_HydrophoneType.Color = 'g';
            end   
            end
        end

        % Value changed function: HydrophoneSerialNumberEditField
        function HydrophoneSerialNumberEditFieldValueChanged(app, event)
            app.HydrophoneSerialNumber = app.HydrophoneSerialNumberEditField.Value;
            app.Lamp_HydrophoneSerialNumber.Color = 'g';    
        end

        % Value changed function: RecorderTypeDropDown
        function RecorderTypeDropDownValueChanged(app, event)
            RecorderType = app.RecorderTypeDropDown.Value;
            if isequal(RecorderType, 'Select ...') 
                app.Lamp_RecorderType.Color = 'r';
            else
            fileID = fopen('.\libs\RecorderType.csv');
            C = textscan(fileID,'%s %s %s %s %s %s %s %s','delimiter',',');
            fclose(fileID);
            matchstr=RecorderType;
            ix=isequal(C{1,3},matchstr);
            idx = find(ix==1);
            if length(idx)>1
                errordlg('More than one Recorder with this name were found. Please report incostency in Recorder type vocabulary to ICES (accessions@ices.dk).')
            else
                app.RecorderType =  C{1,2}(idx);
                app.Lamp_RecorderType.Color = 'g';
            end   
            end
        end

        % Value changed function: RecorderSerialNumberEditField
        function RecorderSerialNumberEditFieldValueChanged(app, event)
            app.RecorderSerialNumber = app.RecorderSerialNumberEditField.Value;
            app.Lamp_RecorderSerialNumber.Color = 'g'; 
        end

        % Value changed function: MeasurementHeightEditField
        function MeasurementHeightEditFieldValueChanged(app, event)
            app.MeasurementHeight = str2num(app.MeasurementHeightEditField.Value);
            app.Lamp_MeasurementHeight.Color = 'g';
        end

        % Value changed function: MeasurementPurposeDropDown
        function MeasurementPurposeDropDownValueChanged(app, event)
            MeasurementPurpose = app.MeasurementPurposeDropDown.Value;
            if isequal(MeasurementPurpose, 'Select ...') 
                app.Lamp_MeasurementPurpose.Color = 'r';
            else
            fileID = fopen('.\libs\MeasurementPurpose.csv');
            C = textscan(fileID,'%s %s %s %s %s %s %s %s','delimiter',',');
            fclose(fileID);
            matchstr=MeasurementPurpose;
            ix=isequal(C{1,3},matchstr);
            idx = find(ix==1);
            if length(idx)>1
                errordlg('More than one ICES - code were found for this measurement purpose. Please report incostency in Recorder type vocabulary to ICES (accessions@ices.dk).')
            else
                app.MeasurementPurpose =  C{1,2}(idx);
                app.Lamp_MeasurementPurpose.Color = 'g';
            end   
            end
        end

        % Value changed function: MeasurementSetupDropDown
        function MeasurementSetupDropDownValueChanged(app, event)
            MeasurementSetup = app.MeasurementSetupDropDown.Value;
            if isequal(MeasurementSetup, 'Select ...') 
                app.Lamp_MeasurementSetup.Color = 'r';
            else
            fileID = fopen('.\libs\MeasurementSetup.csv');
            C = textscan(fileID,'%s %s %s %s %s %s %s %s','delimiter',',');
            fclose(fileID);
            matchstr=MeasurementSetup;
            ix=isequal(C{1,3},matchstr);
            idx = find(ix==1);
            if length(idx)>1
                errordlg('More than one ICES - code were found for this measurement setup. Please report incostency in Recorder type vocabulary to ICES (accessions@ices.dk).')
            else
                app.MeasurementSetup =  C{1,2}(idx);
                app.Lamp_MeasurementSetup.Color = 'g';
            end   
            end
        end

        % Value changed function: RigDesignDropDown
        function RigDesignDropDownValueChanged(app, event)
            RigDesign = app.RigDesignDropDown.Value;
            if isequal(RigDesign, 'Select ...') 
                app.Lamp_RigDesign.Color = 'r';
            else
            fileID = fopen('.\libs\RigDesign.csv');
            C = textscan(fileID,'%s %s %s %s %s %s %s %s','delimiter',',');
            fclose(fileID);
            matchstr=RigDesign;
            ix=isequal(C{1,3},matchstr);
            idx = find(ix==1);
            if length(idx)>1
                errordlg('More than one ICES - code were found for this rig design. Please report incostency in Recorder type vocabulary to ICES (accessions@ices.dk).')
            else
                app.RigDesign =  C{1,2}(idx);
                app.Lamp_RigDesign.Color = 'g';
            end   
            end
        end

        % Value changed function: FrequencyCountSpinner
        function FrequencyCountSpinnerValueChanged(app, event)
            app.FrequencyCount = cast(app.FrequencyCountSpinner.Value,'uint64');
            app.Lamp_FrequencyCount.Color = 'g';
        end

        % Callback function
        function FrequencyIndexEditFieldValueChanged(app, event)
            value = app.FrequencyIndexEditField.Value;
            app.Lamp_FrequencyIndex.Color = 'g';
        end

        % Value changed function: 
        % consecutiveThirdOctaveBandsCheckBox
        function consecutiveThirdOctaveBandsCheckBoxValueChanged(app, event)
            value = app.consecutiveThirdOctaveBandsCheckBox.Value;
            if value == 1
                set(app.LowestTOBDropDown, 'Enable', 'on')
                set(app.HighestTOBDropDown, 'Enable', 'on')
                set(app.FrequencyIndexEditField, 'Enable', 'off')
                set(app.FrequencyIndexEditField, 'Editable', 'off')
                set(app.matButton, 'Enable', 'off')
                set(app.csvButton, 'Enable', 'off')
                set(app.h5Button, 'Enable', 'off')                
                app.individualFrequencyBandsCheckBox.Value = 0;
                app.LoadFrequencyBandsfromCheckBox.Value = 0;
            else
                set(app.LowestTOBDropDown, 'Enable', 'off')
                set(app.HighestTOBDropDown, 'Enable', 'off')
            end
        end

        % Value changed function: individualFrequencyBandsCheckBox
        function individualFrequencyBandsCheckBoxValueChanged(app, event)
            value = app.individualFrequencyBandsCheckBox.Value;
            if value == 0
                set(app.FrequencyIndexEditField, 'Enable', 'off')
            else
                set(app.FrequencyIndexEditField, 'Enable', 'on')
                set(app.FrequencyIndexEditField, 'Editable', 'on')
                set(app.LowestTOBDropDown, 'Enable', 'off')
                set(app.HighestTOBDropDown, 'Enable', 'off')
                set(app.matButton, 'Enable', 'off')
                set(app.csvButton, 'Enable', 'off')
                set(app.h5Button, 'Enable', 'off')
                app.consecutiveThirdOctaveBandsCheckBox.Value = 0;
                app.LoadFrequencyBandsfromCheckBox.Value = 0;
            end
        end

        % Value changed function: LoadFrequencyBandsfromCheckBox
        function LoadFrequencyBandsfromCheckBoxValueChanged(app, event)
            value = app.LoadFrequencyBandsfromCheckBox.Value;
            if value == 0
                set(app.matButton, 'Enable', 'off')
                set(app.csvButton, 'Enable', 'off')
                set(app.h5Button, 'Enable', 'off')
            else
                set(app.matButton, 'Enable', 'on')
                set(app.csvButton, 'Enable', 'on')
                set(app.h5Button, 'Enable', 'on')
                set(app.LowestTOBDropDown, 'Enable', 'off')
                set(app.HighestTOBDropDown, 'Enable', 'off')
                set(app.FrequencyIndexEditField, 'Enable', 'off')
                app.consecutiveThirdOctaveBandsCheckBox.Value = 0;
                app.individualFrequencyBandsCheckBox.Value = 0;
            end
        end

        % Value changed function: LowestTOBDropDown
        function LowestTOBDropDownValueChanged(app, event)
            value = str2num(app.LowestTOBDropDown.Value);
            freqs=load('./libs/freqs.mat');
            freqs=freqs.freqs;
            [~,x] = min(abs(freqs-value));
            if ~isempty(app.fi_u)
                [~,y] = min(abs(app.fi_u-freqs));
                app.fi = double(freqs(x:y));
                app.Lamp_FrequencyIndex.Color = 'g';
            end
            app.fi_l = freqs(x);
            clear freqs
        end

        % Value changed function: HighestTOBDropDown
        function HighestTOBDropDownValueChanged(app, event)
            value = str2num(app.HighestTOBDropDown.Value);
            freqs=load('./libs/freqs.mat');
            freqs=freqs.freqs;
            [~,y] = min(abs(value-freqs));
            if ~isempty(app.fi_l)
                [~,x] = min(abs(app.fi_l-freqs));
                app.fi = double(freqs(x:y));
                app.Lamp_FrequencyIndex.Color = 'g';
            end
            app.fi_u = freqs(y);  
            clear freqs
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FileInformationTab = uitab(app.TabGroup);
            app.FileInformationTab.Title = 'File Information';
            app.EmailEditFieldLabel = uilabel(app.FileInformationTab);
            app.EmailEditFieldLabel.Text = 'Email';
            app.EmailEditField = uieditfield(app.FileInformationTab, 'text');
            app.EmailEditField.ValueChangedFcn = createCallbackFcn(app, @EmailEditFieldValueChanged, true);
            app.EmailEditField.Value = 'test@test.com';
            app.CreationDateDatePickerLabel = uilabel(app.FileInformationTab);
            app.CreationDateDatePickerLabel.Text = 'Creation Date';
            app.CreationDateDatePicker = uidatepicker(app.FileInformationTab);
            app.CreationDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @CreationDateDatePickerValueChanged, true);
            app.StartDateDatePickerLabel = uilabel(app.FileInformationTab);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDateDatePicker = uidatepicker(app.FileInformationTab);
            app.StartDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @StartDateDatePickerValueChanged, true);
            app.EndDateDatePickerLabel = uilabel(app.FileInformationTab);
            app.EndDateDatePickerLabel.Text = 'End Date';
            app.EndDateDatePicker = uidatepicker(app.FileInformationTab);
            app.EndDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @EndDateDatePickerValueChanged, true);
            app.ContactEditFieldLabel = uilabel(app.FileInformationTab);
            app.ContactEditFieldLabel.Text = 'Contact';
            app.ContactEditField = uieditfield(app.FileInformationTab, 'text');
            app.ContactEditField.ValueChangedFcn = createCallbackFcn(app, @ContactEditFieldValueChanged, true);
            app.ContactEditField.Value = 'test@test.com';
            app.CountryCodeDropDownLabel = uilabel(app.FileInformationTab);
            app.CountryCodeDropDownLabel.Text = 'Country Code';
            app.CountryCodeDropDown = uidropdown(app.FileInformationTab);
            app.CountryCodeDropDown.Items = {'Select ...', 'DE', 'DK', 'EE', 'FI', 'LT', 'LV', 'PL', 'RU', 'SE'};
            app.CountryCodeDropDown.ValueChangedFcn = createCallbackFcn(app, @CountryCodeDropDownValueChanged, true);
            app.CountryCodeDropDown.Value = 'Select ...';
            app.TimeEditFieldLabel = uilabel(app.FileInformationTab);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.FileInformationTab, 'text');
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.TimeEditField.Value = 'HH:mm';
            app.TimeEditField_2Label = uilabel(app.FileInformationTab);
            app.TimeEditField_2Label.Text = 'Time';
            app.TimeEditField_2 = uieditfield(app.FileInformationTab, 'text');
            app.TimeEditField_2.ValueChangedFcn = createCallbackFcn(app, @TimeEditField_2ValueChanged, true);
            app.TimeEditField_2.Value = 'HH:mm';
            app.TimeEditField_3Label = uilabel(app.FileInformationTab);
            app.TimeEditField_3Label.Text = 'Time';
            app.TimeEditField_3 = uieditfield(app.FileInformationTab, 'text');
            app.TimeEditField_3.ValueChangedFcn = createCallbackFcn(app, @TimeEditField_3ValueChanged, true);
            app.TimeEditField_3.Value = 'HH:mm';
            app.InstitutionEditFieldLabel = uilabel(app.FileInformationTab);
            app.InstitutionEditFieldLabel.Text = 'Institution';
            app.InstitutionEditField = uieditfield(app.FileInformationTab, 'text');
            app.InstitutionEditField.ValueChangedFcn = createCallbackFcn(app, @InstitutionEditFieldValueChanged, true);
            app.InstitutionEditField.Value = 'Search';
            app.Lamp_Email = uilamp(app.FileInformationTab);
            app.Lamp_CreationDate = uilamp(app.FileInformationTab);
            app.Lamp_StartDate = uilamp(app.FileInformationTab);
            app.Lamp_EndDate = uilamp(app.FileInformationTab);
            app.Lamp_Institution = uilamp(app.FileInformationTab);
            app.Lamp_Contact = uilamp(app.FileInformationTab);
            app.Lamp_CountryCode = uilamp(app.FileInformationTab);
            app.Lamp_StationCode = uilamp(app.FileInformationTab);
            app.SelectDropDownLabel = uilabel(app.FileInformationTab);
            app.SelectDropDownLabel.Text = 'Select';
            app.SelectDropDown = uidropdown(app.FileInformationTab);
            app.SelectDropDown.Items = {'Search first ...', ''};
            app.SelectDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectDropDownValueChanged, true);
            app.SelectDropDown.Value = 'Search first ...';
            app.StationCodeEditFieldLabel = uilabel(app.FileInformationTab);
            app.StationCodeEditFieldLabel.Text = 'Station Code';
            app.StationCodeEditField = uieditfield(app.FileInformationTab, 'text');
            app.StationCodeEditField.ValueChangedFcn = createCallbackFcn(app, @StationCodeEditFieldValueChanged, true);
            app.StationCodeEditField.Value = 'Search';
            app.SelectDropDown_2Label = uilabel(app.FileInformationTab);
            app.SelectDropDown_2Label.Text = 'Select';
            app.SelectDropDown_StationCode = uidropdown(app.FileInformationTab);
            app.SelectDropDown_StationCode.Items = {'Search first ...', ''};
            app.SelectDropDown_StationCode.ValueChangedFcn = createCallbackFcn(app, @SelectDropDown_StationCodeValueChanged, true);
            app.SelectDropDown_StationCode.Value = 'Search first ...';
            app.Image = uiimage(app.FileInformationTab);
            app.Image.Tooltip = {'Creator of the HDF5 file/ who holds responsibility for data QA and'; 'creation of the submited hdf5 file.'};
            app.Image.ImageSource = 'info.png';
            app.Image_2 = uiimage(app.FileInformationTab);
            app.Image_2.Tooltip = {'Date of file creation. UTC DateTime in ISO 8601 format: YYYY-MMDDThh:'; 'mm[:ss] or YYYY-MM-DD hh:mm[:ss] . Seconds are optional.'; 'For example:'; '2020-04-01 11:36Z, or'; '2020-04-01 11:36:23Z'};
            app.Image_2.ImageSource = 'info.png';
            app.Image_3 = uiimage(app.FileInformationTab);
            app.Image_3.Tooltip = {'Measurement collection start date. UTC DateTime in ISO 8601'; 'format: YYYY-MM-DDThh:mm[:ss] or YYYY-MM-DD hh:mm[:ss].'; 'Seconds are optional.'; 'For example:'; '2020-04-01 11:36Z, or'; '2020-04-01 11:36:23Z'};
            app.Image_3.ImageSource = 'info.png';
            app.Image_4 = uiimage(app.FileInformationTab);
            app.Image_4.Tooltip = {'Measurement collection end date. UTC DateTime in ISO 8601'; 'format: YYYY-MM-DDThh:mm[:ss] or YYYY-MM-DD hh:mm[:ss].'; 'Seconds are optional.'; 'For example:'; '2020-04-01 11:36Z, or'; '2020-04-01 11:36:23Z'};
            app.Image_4.ImageSource = 'info.png';
            app.Image_5 = uiimage(app.FileInformationTab);
            app.Image_5.Tooltip = {'Institution which acquired the data. https://vocab.ices.dk/?ref=1398'};
            app.Image_5.ImageSource = 'info.png';
            app.Image_6 = uiimage(app.FileInformationTab);
            app.Image_6.Tooltip = {'Contact of all future external queries/who submits/holds'; 'responsibility for submission'};
            app.Image_6.ImageSource = 'info.png';
            app.Image_7 = uiimage(app.FileInformationTab);
            app.Image_7.Tooltip = {'https://vocab.ices.dk/?ref=337'};
            app.Image_7.ImageSource = 'info.png';
            app.Image_8 = uiimage(app.FileInformationTab);
            app.Image_8.Tooltip = {'The station code and its associated coordinates can be found in the'; 'ICES station dictionary'; ''; 'https://vocab.ices.dk/?ref=1399'; ''};
            app.Image_8.ImageSource = 'info.png';
            app.MetadataTab = uitab(app.TabGroup);
            app.MetadataTab.Title = 'Metadata';
            app.CreateUUIDButton = uibutton(app.MetadataTab, 'push');
            app.CreateUUIDButton.Text = 'Create UUID';
            app.HydrophoneTypeDropDownLabel = uilabel(app.MetadataTab);
            app.HydrophoneTypeDropDownLabel.Text = 'Hydrophone Type';
            app.HydrophoneTypeDropDown = uidropdown(app.MetadataTab);
            app.HydrophoneTypeDropDown.Items = {'Select ...', 'Brüel & Kjaer 8103', 'Brüel & Kjaer 8104 ', 'Brüel & Kjaer 8105', 'Brüel & Kjaer 8106', 'COLMAR GP1516', 'DE-200', 'DE-600', 'DE-PRO', 'GeoSpectrum M14-360 ', 'GeoSpectrum M14-900', 'GeoSpectrum M15-360', 'GeoSpectrum M15-900', 'GeoSpectrum M36-100', 'GeoSpectrum M36-900', 'GeoSpectrum M5-360', 'GeoSpectrum M5-900', 'HTI-15-Min', 'HTI-90-UReftek 17', 'HTI-94-SSQ', 'HTI-96-Min', 'HTI-97-DA', 'HTI-99-HF', 'HTI-99-UHF', 'Integrated in recorder', 'Soundtrap external hydrophone', 'Sparton'};
            app.HydrophoneTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @HydrophoneTypeDropDownValueChanged, true);
            app.HydrophoneTypeDropDown.Value = 'Select ...';
            app.HydrophoneSerialNumberEditFieldLabel = uilabel(app.MetadataTab);
            app.HydrophoneSerialNumberEditFieldLabel.Text = 'Hydrophone Serial Number';
            app.HydrophoneSerialNumberEditField = uieditfield(app.MetadataTab, 'text');
            app.HydrophoneSerialNumberEditField.ValueChangedFcn = createCallbackFcn(app, @HydrophoneSerialNumberEditFieldValueChanged, true);
            app.RecorderTypeDropDownLabel = uilabel(app.MetadataTab);
            app.RecorderTypeDropDownLabel.Text = 'Recorder Type';
            app.RecorderTypeDropDown = uidropdown(app.MetadataTab);
            app.RecorderTypeDropDown.Items = {'Select ...', 'JASCO Amar', 'Loggerhead DSG', 'Loggerhead DSG-ST', 'Loggerhead LS1/LS2', 'Loggerhead Medusa Acoustic Drifter', 'Loggerhead SNAP', 'OceanInstruments SOUNDTRAP 300 HF', 'OceanInstruments SOUNDTRAP 300 STD', 'OceanInstruments SOUNDTRAP 500 HF', 'OceanInstruments SOUNDTRAP 500 STD', 'OceanSonics icListen 200kHz', 'RTSys EASDA14 320', 'RTSys Sylence-LP recorder', 'Wildlife SM2M', 'Wildlife SM2M+', 'Wildlife SM3M', 'Wildlife SM3M+', 'Wildlife SM4M'};
            app.RecorderTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @RecorderTypeDropDownValueChanged, true);
            app.RecorderTypeDropDown.Value = 'Select ...';
            app.RecorderSerialNumberEditFieldLabel = uilabel(app.MetadataTab);
            app.RecorderSerialNumberEditFieldLabel.Text = 'Recorder Serial Number';
            app.RecorderSerialNumberEditField = uieditfield(app.MetadataTab, 'text');
            app.RecorderSerialNumberEditField.ValueChangedFcn = createCallbackFcn(app, @RecorderSerialNumberEditFieldValueChanged, true);
            app.MeasurementHeightEditFieldLabel = uilabel(app.MetadataTab);
            app.MeasurementHeightEditFieldLabel.Text = 'Measurement Height';
            app.MeasurementHeightEditField = uieditfield(app.MetadataTab, 'text');
            app.MeasurementHeightEditField.ValueChangedFcn = createCallbackFcn(app, @MeasurementHeightEditFieldValueChanged, true);
            app.MeasurementPurposeDropDownLabel = uilabel(app.MetadataTab);
            app.MeasurementPurposeDropDownLabel.Text = 'Measurement Purpose';
            app.MeasurementPurposeDropDown = uidropdown(app.MetadataTab);
            app.MeasurementPurposeDropDown.Items = {'Select ...', 'Commissioned measurements', 'HELCOM monitoring', 'National monitoring', 'OSPAR monitoring', 'Other', 'Project', 'Research'};
            app.MeasurementPurposeDropDown.ValueChangedFcn = createCallbackFcn(app, @MeasurementPurposeDropDownValueChanged, true);
            app.MeasurementPurposeDropDown.Value = 'Select ...';
            app.MeasurementSetupDropDownLabel = uilabel(app.MetadataTab);
            app.MeasurementSetupDropDownLabel.Text = 'Measurement Setup';
            app.MeasurementSetupDropDown = uidropdown(app.MetadataTab);
            app.MeasurementSetupDropDown.Items = {'Select ...', 'Autonomous', 'Cable mounted', 'Combined'};
            app.MeasurementSetupDropDown.ValueChangedFcn = createCallbackFcn(app, @MeasurementSetupDropDownValueChanged, true);
            app.MeasurementSetupDropDown.Value = 'Select ...';
            app.RigDesignDropDownLabel = uilabel(app.MetadataTab);
            app.RigDesignDropDownLabel.Text = 'Rig Design';
            app.RigDesignDropDown = uidropdown(app.MetadataTab);
            app.RigDesignDropDown.Items = {'Select ...', 'Bottom frame', 'From vessel', 'Glider', 'Mooring with floating buoy', 'Other', 'Supended from surface', 'Vertical measurement chain'};
            app.RigDesignDropDown.ValueChangedFcn = createCallbackFcn(app, @RigDesignDropDownValueChanged, true);
            app.RigDesignDropDown.Value = 'Select ...';
            app.FrequencyCountSpinnerLabel = uilabel(app.MetadataTab);
            app.FrequencyCountSpinnerLabel.Text = 'Frequency Count';
            app.FrequencyCountSpinner = uispinner(app.MetadataTab);
            app.FrequencyCountSpinner.Limits = [1 48];
            app.FrequencyCountSpinner.ValueChangedFcn = createCallbackFcn(app, @FrequencyCountSpinnerValueChanged, true);
            app.FrequencyCountSpinner.Value = 1;
            app.FrequencyUnitDropDownLabel = uilabel(app.MetadataTab);
            app.FrequencyUnitDropDownLabel.Text = 'Frequency Unit';
            app.FrequencyUnitDropDown = uidropdown(app.MetadataTab);
            app.ChannelCountSpinnerLabel = uilabel(app.MetadataTab);
            app.ChannelCountSpinnerLabel.Text = 'Channel Count';
            app.ChannelCountSpinner = uispinner(app.MetadataTab);
            app.MeasurementTotalNumbersSpinnerLabel = uilabel(app.MetadataTab);
            app.MeasurementTotalNumbersSpinnerLabel.Text = 'Measurement Total Numbers';
            app.MeasurementTotalNumbersSpinner = uispinner(app.MetadataTab);
            app.MeasurementUnitDropDownLabel = uilabel(app.MetadataTab);
            app.MeasurementUnitDropDownLabel.Text = 'Measurement Unit';
            app.MeasurementUnitDropDown = uidropdown(app.MetadataTab);
            app.AveragingTimeSpinnerLabel = uilabel(app.MetadataTab);
            app.AveragingTimeSpinnerLabel.Text = 'Averaging Time';
            app.AveragingTimeSpinner = uispinner(app.MetadataTab);
            app.ProcessingAlgorithmDropDownLabel = uilabel(app.MetadataTab);
            app.ProcessingAlgorithmDropDownLabel.Text = 'Processing Algorithm';
            app.ProcessingAlgorithmDropDown = uidropdown(app.MetadataTab);
            app.DataUUIDEditFieldLabel = uilabel(app.MetadataTab);
            app.DataUUIDEditFieldLabel.Text = 'Data UUID';
            app.DataUUIDEditField = uieditfield(app.MetadataTab, 'text');
            app.DataUUIDEditField.Editable = 'off';
            app.DatasetVersionSpinnerLabel = uilabel(app.MetadataTab);
            app.DatasetVersionSpinnerLabel.Text = 'Dataset Version';
            app.DatasetVersionSpinner = uispinner(app.MetadataTab);
            app.CalibrationProcedureDropDownLabel = uilabel(app.MetadataTab);
            app.CalibrationProcedureDropDownLabel.Text = 'Calibration Procedure';
            app.CalibrationProcedureDropDown = uidropdown(app.MetadataTab);
            app.CalibrationDatetimeEditFieldLabel = uilabel(app.MetadataTab);
            app.CalibrationDatetimeEditFieldLabel.Text = 'Calibration Datetime';
            app.CalibrationDatetimeEditField = uieditfield(app.MetadataTab, 'text');
            app.CommentsEditFieldLabel = uilabel(app.MetadataTab);
            app.CommentsEditFieldLabel.Text = 'Comments';
            app.CommentsEditField = uieditfield(app.MetadataTab, 'text');
            app.Lamp_HydrophoneType = uilamp(app.MetadataTab);
            app.Lamp_HydrophoneSerialNumber = uilamp(app.MetadataTab);
            app.Lamp_RecorderType = uilamp(app.MetadataTab);
            app.Lamp_RecorderSerialNumber = uilamp(app.MetadataTab);
            app.Lamp_MeasurementHeight = uilamp(app.MetadataTab);
            app.Lamp_MeasurementPurpose = uilamp(app.MetadataTab);
            app.Lamp_MeasurementSetup = uilamp(app.MetadataTab);
            app.Lamp_RigDesign = uilamp(app.MetadataTab);
            app.Lamp_FrequencyCount = uilamp(app.MetadataTab);
            app.Lamp_FrequencyIndex = uilamp(app.MetadataTab);
            app.FrequencyIndexPanel = uipanel(app.MetadataTab);
            app.FrequencyIndexPanel.Title = 'Frequency Index';
            app.individualFrequencyBandsCheckBox = uicheckbox(app.FrequencyIndexPanel);
            app.individualFrequencyBandsCheckBox.ValueChangedFcn = createCallbackFcn(app, @individualFrequencyBandsCheckBoxValueChanged, true);
            app.individualFrequencyBandsCheckBox.Text = 'individual Frequency- Bands';
            app.consecutiveThirdOctaveBandsCheckBox = uicheckbox(app.FrequencyIndexPanel);
            app.consecutiveThirdOctaveBandsCheckBox.ValueChangedFcn = createCallbackFcn(app, @consecutiveThirdOctaveBandsCheckBoxValueChanged, true);
            app.consecutiveThirdOctaveBandsCheckBox.Text = 'consecutive Third-Octave Bands';
            app.consecutiveThirdOctaveBandsCheckBox.Value = true;
            app.LowestTOBDropDownLabel = uilabel(app.FrequencyIndexPanel);
            app.LowestTOBDropDownLabel.Text = 'Lowest TOB';
            app.LowestTOBDropDown = uidropdown(app.FrequencyIndexPanel);
            app.LowestTOBDropDown.Items = {'Select ...', '10', '12.5', '16', '20', '25', '31.5', '40', '50', '63', '80', '100', '125', '160', '200', '250', '315', '400', '500', '630', '800', '1000', '1250', '1600', '2000', '2500', '3150', '4000', '5000', '6300', '8000', '10000', '12500', '16000', '20000', '40000', '80000', '160000', ''};
            app.LowestTOBDropDown.ValueChangedFcn = createCallbackFcn(app, @LowestTOBDropDownValueChanged, true);
            app.LowestTOBDropDown.Value = 'Select ...';
            app.HighestTOBDropDownLabel = uilabel(app.FrequencyIndexPanel);
            app.HighestTOBDropDownLabel.Text = 'Highest TOB';
            app.HighestTOBDropDown = uidropdown(app.FrequencyIndexPanel);
            app.HighestTOBDropDown.Items = {'Select ...', '10', '12.5', '16', '20', '25', '31.5', '40', '50', '63', '80', '100', '125', '160', '200', '250', '315', '400', '500', '630', '800', '1000', '1250', '1600', '2000', '2500', '3150', '4000', '5000', '6300', '8000', '10000', '12500', '16000', '20000', '40000', '80000', '160000'};
            app.HighestTOBDropDown.ValueChangedFcn = createCallbackFcn(app, @HighestTOBDropDownValueChanged, true);
            app.HighestTOBDropDown.Value = 'Select ...';
            app.FrequencyIndexEditField = uieditfield(app.FrequencyIndexPanel, 'text');
            app.FrequencyIndexEditField.Editable = 'off';
            app.FrequencyIndexEditField.Enable = 'off';
            app.FrequencyIndexEditField.Value = '[63,125,2000]';
            app.LoadFrequencyBandsfromCheckBox = uicheckbox(app.FrequencyIndexPanel);
            app.LoadFrequencyBandsfromCheckBox.ValueChangedFcn = createCallbackFcn(app, @LoadFrequencyBandsfromCheckBoxValueChanged, true);
            app.LoadFrequencyBandsfromCheckBox.Text = 'Load Frequency Bands from';
            app.matButton = uibutton(app.FrequencyIndexPanel, 'push');
            app.matButton.Enable = 'off';
            app.matButton.Text = '.mat';
            app.csvButton = uibutton(app.FrequencyIndexPanel, 'push');
            app.csvButton.Enable = 'off';
            app.csvButton.Text = '.csv';
            app.h5Button = uibutton(app.FrequencyIndexPanel, 'push');
            app.h5Button.Enable = 'off';
            app.h5Button.Text = '.h5';
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.SelectDatetimeVectorButton = uibutton(app.DataTab, 'push');
            app.SelectDatetimeVectorButton.Text = 'Select Datetime Vector';
            app.DateTimeLabel = uilabel(app.DataTab);
            app.DateTimeLabel.Text = 'DateTime';
            app.SelectLeqMeasurementsofChannel1Button = uibutton(app.DataTab, 'push');
            app.SelectLeqMeasurementsofChannel1Button.Text = 'Select LeqMeasurements of Channel 1';
            app.LeqMeasurementsofChannel1Label = uilabel(app.DataTab);
            app.LeqMeasurementsofChannel1Label.Text = 'Leq Measurements of Channel 1';
            app.SelectLeqMeasurementsofChannel2Button = uibutton(app.DataTab, 'push');
            app.SelectLeqMeasurementsofChannel2Button.Text = 'Select LeqMeasurements of Channel 2';
            app.LeqMeasurementsofChannel2Label = uilabel(app.DataTab);
            app.LeqMeasurementsofChannel2Label.Text = 'Leq Measurements of Channel 2';
            app.SelectLeqMeasurementsofChannel3Button = uibutton(app.DataTab, 'push');
            app.SelectLeqMeasurementsofChannel3Button.Text = 'Select LeqMeasurements of Channel 3';
            app.LeqMeasurementsofChannel3Label = uilabel(app.DataTab);
            app.LeqMeasurementsofChannel3Label.Text = 'Leq Measurements of Channel 3';
            app.SelectLeqMeasurementsofChannel4Button = uibutton(app.DataTab, 'push');
            app.SelectLeqMeasurementsofChannel4Button.Text = 'Select LeqMeasurements of Channel 4';
            app.LeqMeasurementsofChannel4Label = uilabel(app.DataTab);
            app.LeqMeasurementsofChannel4Label.Text = 'Leq Measurements of Channel 4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = format3000

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end